classdef ManualFrontExtraction_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RootDirEditField        matlab.ui.control.EditField
        RootDirEditFieldLabel   matlab.ui.control.Label
        NextButton_2            matlab.ui.control.Button
        SelectNonFrontButton    matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        SelectBoreFrontButton   matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        IntervalEditField       matlab.ui.control.NumericEditField
        IntervalEditFieldLabel  matlab.ui.control.Label
        EndEditField            matlab.ui.control.NumericEditField
        EndEditFieldLabel       matlab.ui.control.Label
        StartEditField          matlab.ui.control.NumericEditField
        StartEditFieldLabel     matlab.ui.control.Label
        LoadPlotButton          matlab.ui.control.Button
        FileNumDropDownLabel    matlab.ui.control.Label
        FileNumDropDown         matlab.ui.control.DropDown
        DayDropDown             matlab.ui.control.DropDown
        DayDropDownLabel        matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        file0% store current image path
        imI % index of current image on display
        IMG % store current image data
        X   % store cross-shore coordinate
        Y   % store cross-shore coordinage
        Nt  % number of time-steps
        Nx  % Number of X
        Ny  % Number of Y
        frontFile% path to log file
        frontData% data taken from frontFile
        frontRow% store current front
        frontCol% store current front
        frontN% current front number within image
        roiFront% polygon object for fronts
        nonFrontFile% control for non-front pixels
        nonFrontData%
        nonFrontRow%
        nonFrontCol%
        nonFrontN%
        roiNonFront
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function load_and_plot_image(app)
             if isempty(app.IMG) || any(app.file0~=app.FileNumDropDown.Value)
                app.file0 = app.FileNumDropDown.Value;
                app.TextArea.Value = ['loading data from: ',app.file0];
                load(app.file0,'I','x','y')  
                app.X=x(1,:);
                app.Y=y(:,1);
                app.IMG=I;
                [app.Ny,app.Nx,app.Nt]=size(I);
            end

            index = app.StartEditField.Value;
            next  = index+app.IntervalEditField.Value;
            switch index
                case next>=app.Nt
                    next = app.Nt;
                case next>=app.EndEditField.Value
                    next = app.EndEditField.Value;
            end

            
            app.StartEditField.Value=next;
            for kk = index:next
                imagesc(app.UIAxes, app.X, app.Y, app.IMG(:,:,kk)) 
                colormap(app.UIAxes,'bone')
                axis(app.UIAxes,'equal')
                close(gcf)
                %set(app.UIAxes,'plotboxaspectratio',get(app.UIAxes,'DataAspectRatio'))
                pause(0.1)
            end
            app.imI=next;
            app.TextArea.Value = 'ready for front selection!';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DayDropDown
        function DayDropDownValueChanged(app, event)
%            app.DayDropDown
%             event
%             latest_value = event.Value
%             app.DayDropDown.Value=latest_value;
            %app.DayDropDown.ItemsData = ['/home/derek/projects/ShortCrests/IMG/data/',latest_value,'/'];
            %DayDropDownOpening(app,app.DayDropDown.ItemsData)
%             app.DayDropDown.ItemsData = ['/home/derek/projects/ShortCrests/IMG/data/',app.DayDropDown.Value,'/'];
            files = dir([app.DayDropDown.Value,'*.mat']); 
            nf = length(files);
            name = cell(1,nf);
            for ii = 1:length(files), 
                name = files(ii).name;
                Items{1,ii} = name(24:29);
                ItemsData{1,ii}=[files(ii).folder,'/',name]; 
            end
            app.FileNumDropDown.Items = Items;
            app.FileNumDropDown.ItemsData=ItemsData;
%             app.FileNumDropDown.Value
        end

        % Value changed function: FileNumDropDown
        function FileNumDropDownValueChanged(app, event)
            latest_value = event.Value;
            app.FileNumDropDown.Value = latest_value;
            %draw it!
        end

        % Drop down opening function: DayDropDown
        function DayDropDownOpening(app, event)
           if isempty(app.RootDirEditField.Value)
               app.RootDirEditField.Value = './'
           end
           rdir = [app.RootDirEditField.Value, '/mat_data/'];
           froot= 'processed_full_IMG_';
           dirs = dir([rdir,froot,'*.mat']);
           iter = 1;
           Items = {''};
           ind = length(froot)+1;
           for ii=1:length(dirs)
               str = dirs(ii).name(ind + [0:7]);
               if isempty(Items)
                   Items{1,iter} = str(5:8);
                   ItemsData{1,iter} = [rdir,froot,str];
                   iter = iter+1;
               else
               isItem = ismember(str(5:8),Items);
               end
               if ~isItem
                   Items{1,iter} = str(5:8);
                   ItemsData{1,iter}=[rdir,froot,str];
                   iter = iter+1;
               end
           end
%               dirs = dir('/home/derek/projects/ShortCrests/IMG/data/');
%               iter=1;
%               for ii=1:length(dirs)
%                   if length(dirs(ii).name)==4,
%                       Items{1,iter} = dirs(ii).name;
%                       ItemsData{1,iter}=[dirs(ii).folder,'/',dirs(ii).name];
%                       iter=iter+1;
%                   end
%               end
              app.DayDropDown.Items = Items;
              app.DayDropDown.ItemsData=ItemsData;
        end

        % Drop down opening function: FileNumDropDown
        function FileNumDropDownOpening(app, event)
         files = dir([app.DayDropDown.Value,'*.mat']) 
            nf = length(files);
            name = cell(1,nf);
            for ii = 1:length(files), 
                name = files(ii).name;
                Items{1,ii} = name(24:29);
                ItemsData{1,ii}=[files(ii).folder,'/',name]; 
            end
            app.FileNumDropDown.Items = Items;
            app.FileNumDropDown.ItemsData=ItemsData;
         
        end

        % Button pushed function: LoadPlotButton
        function LoadPlotButtonPushed(app, event)
            if app.StartEditField.Value>=app.EndEditField.Value
                app.TextArea.Value=['Current Image Index:', num2str(app.StartEditField.Value),' >= End Value: ',num2str(app.EndEditField.Value)];
                return
            else
                app.TextArea.Value='processing request...';
            end
            load_and_plot_image(app);
        end

        % Button pushed function: SelectBoreFrontButton
        function SelectBoreFrontButtonPushed(app, event)
            % get a polygon from image...
            % first get current input file
            fin = app.FileNumDropDown.Value;
            dirs= dir(fin);
            str = regexp(fin,'(?<=_IMG_).*(?=.mat)','match');
            fout = [dirs.folder,'/','frontData_',str{1},'.mat'];
%             str = split(str,'_');
%             mmdd= str{1};
%             hhmm= num2str(str2num(str{2}(1:2))*100);
%             rdir = [app.RootDirEditField.Value, '/data/'];
%             dirs = dir([rdir,mmdd,filesep,hhmm,'/*.avi']);
%             for ii = 1:length(dirs)
%                 if all(dirs(ii).name(29:43)==str);
%                     fout = [dirs(ii).folder,'/','frontData_',dirs(ii).name(9:end)];
%                     break
%                 end
%             end
            app.frontFile = fout;
            if exist(app.frontFile,'file')
               app.frontData = load(app.frontFile);
               app.frontN = app.frontData.frontN(end) + 1;
            else
               app.frontData.imI = [];
               app.frontData.frontN = [];
               app.frontData.frontRow = [];
               app.frontData.frontCol = [];
               app.frontN = 1;
            end
            app.TextArea.Value='Select points along a continuous bore front... double click when finished selecting.';
            app.roiFront = drawpolyline(app.UIAxes);
            app.TextArea.Value='Use cursor to adjust points. Press <Next> to save Fronts and re-initialize.';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
             
            [xx,yy]=meshgrid(app.X,app.Y);
             for jj = 1:size(app.roiFront.Position,1)
                 [~,index] = min( sqrt( (app.roiFront.Position(jj,1) - xx(:)).^2 + (app.roiFront.Position(jj,2)-yy(:)).^2 )  );
                 [app.frontRow(jj,1), app.frontCol(jj,1)] = ind2sub(size(xx),index); 
             end
             if ~isempty(app.frontData.frontN)
                imI   = cat(1,app.frontData.imI,app.imI*ones(jj,1));
                frontN   = cat(1,app.frontData.frontN,app.frontN*ones(jj,1));
                frontRow = cat(1,app.frontData.frontRow,app.frontRow);
                frontCol = cat(1,app.frontData.frontCol,app.frontCol);
             else
                 imI = app.imI*ones(jj,1);
                 frontN = app.frontN*ones(jj,1);
                 frontRow=app.frontRow;
                 frontCol=app.frontCol;
             end
             save(app.frontFile,'imI','frontN','frontRow','frontCol')
             app.frontN = [];
             app.frontCol = [];
             app.frontRow = [];
             app.TextArea.Value='Press "select bore front" to add another front, "select non-front" to add null values, or "load & plot" to move on to next image.';
        end

        % Button pushed function: SelectNonFrontButton
        function SelectNonFrontButtonPushed(app, event)
            fin = app.FileNumDropDown.Value;
            dirs= dir(fin);
            str = regexp(fin,'(?<=_IMG_).*(?=.mat)','match');
            fout = [dirs.folder,'/','nonFrontData_',str{1},'.mat'];
%             str = fin(61:end-4);
%             mmdd= str(5:8);
%             rdir = [app.RootDirEditField.Value, '/data/'];
%             dirs = dir(['/2013',mmdd,'/rawData_OCM*.avi']);
%             for ii = 1:length(dirs)
%                 if all(dirs(ii).name(29:43)==str);
%                     fout = [dirs(ii).folder,'/','nonFrontData_',dirs(ii).name(9:end)];
%                     break
%                 end
%             end
            app.nonFrontFile = fout;
            if exist(app.nonFrontFile,'file')
               app.nonFrontData = load(app.nonFrontFile);
               app.nonFrontN = app.nonFrontData.nonFrontN(end) + 1;
            else
               app.nonFrontData.imI = [];
               app.nonFrontData.nonFrontN = [];
               app.nonFrontData.nonFrontRow = [];
               app.nonFrontData.nonFrontCol = [];
               app.nonFrontN = 1;
            end
            app.TextArea.Value='Select points NOT associated with a bore front... double click when finished.';
            app.roiNonFront = drawpolyline(app.UIAxes);
            app.TextArea.Value='Use cursor to adjust points. Press <Next> to save Non-Fronts and re-initialize.';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
                [xx,yy]=meshgrid(app.X,app.Y);
             for jj = 1:size(app.roiNonFront.Position,1)
                 [~,index] = min( sqrt( (app.roiNonFront.Position(jj,1) - xx(:)).^2 + (app.roiNonFront.Position(jj,2)-yy(:)).^2 )  );
                 [app.nonFrontRow(jj,1), app.nonFrontCol(jj,1)] = ind2sub(size(xx),index); 
             end
             if ~isempty(app.nonFrontData.nonFrontN)
                imI         = cat(1,app.nonFrontData.imI,app.imI*ones(jj,1));
                nonFrontN   = cat(1,app.nonFrontData.nonFrontN,app.nonFrontN*ones(jj,1));
                nonFrontRow = cat(1,app.nonFrontData.nonFrontRow,app.nonFrontRow);
                nonFrontCol = cat(1,app.nonFrontData.nonFrontCol,app.nonFrontCol);
             else
                 imI = app.imI*ones(jj,1);
                 nonFrontN = app.nonFrontN*ones(jj,1);
                 nonFrontRow=app.nonFrontRow;
                 nonFrontCol=app.nonFrontCol;
             end
             save(app.nonFrontFile,'imI','nonFrontN','nonFrontRow','nonFrontCol')
             app.nonFrontN = [];
             app.nonFrontCol = [];
             app.nonFrontRow = [];
             app.TextArea.Value='Press "select bore front" to add another front, "select non-front" to add null values, or "load & plot" to move on to next image.';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DayDropDownLabel = uilabel(app.LeftPanel);
            app.DayDropDownLabel.Text = 'Day';
            app.DayDropDown = uidropdown(app.LeftPanel);
            app.DayDropDown.Items = {'0929'};
            app.DayDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DayDropDownOpening, true);
            app.DayDropDown.ValueChangedFcn = createCallbackFcn(app, @DayDropDownValueChanged, true);
            app.DayDropDown.Value = '0929';
            app.FileNumDropDown = uidropdown(app.LeftPanel);
            app.FileNumDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FileNumDropDownOpening, true);
            app.FileNumDropDown.ValueChangedFcn = createCallbackFcn(app, @FileNumDropDownValueChanged, true);
            app.FileNumDropDownLabel = uilabel(app.LeftPanel);
            app.FileNumDropDownLabel.Text = 'File Num:';
            app.LoadPlotButton = uibutton(app.LeftPanel, 'push');
            app.LoadPlotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotButtonPushed, true);
            app.LoadPlotButton.Text = 'Load & Plot';
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartEditField.RoundFractionalValues = 'on';
            app.StartEditField.Value = 1;
            app.EndEditFieldLabel = uilabel(app.LeftPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndEditField.Value = 100;
            app.IntervalEditFieldLabel = uilabel(app.LeftPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalEditField.Value = 10;
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.SelectBoreFrontButton = uibutton(app.LeftPanel, 'push');
            app.SelectBoreFrontButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBoreFrontButtonPushed, true);
            app.SelectBoreFrontButton.Text = 'Select Bore Front';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectNonFrontButton = uibutton(app.LeftPanel, 'push');
            app.SelectNonFrontButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNonFrontButtonPushed, true);
            app.SelectNonFrontButton.Text = 'Select Non-Front';
            app.NextButton_2 = uibutton(app.LeftPanel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.RootDirEditFieldLabel = uilabel(app.LeftPanel);
            app.RootDirEditFieldLabel.Text = 'Root Dir';
            app.RootDirEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManualFrontExtraction_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end