classdef select_CNN_training_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadVideoButton           matlab.ui.control.Button
        StartFrameEditField       matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel  matlab.ui.control.Label
        SelectCurrentFilename     matlab.ui.control.DropDown
        SelectFileLabel           matlab.ui.control.Label
        SelectCurrentDirectory    matlab.ui.control.DropDown
        SelectDirectoryLabel      matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        SelectFiles               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path % path to video files
        info % structure w/ video info 
        icp  % structure w/ intrinsic cam parameters
        beta0% extrinsic cam params
        vid  % structure w/ current video
        U    % image horizontal coord
        V    % image vertical coord
        X    % grid cross-shore coord
        Y    % grid alongshore coord
        Uint % (x,y) U-coord in image
        Vint % (x,y) V-coord in image
        IMG  % current rectified image frame
        imageOutputDirecotry % this is where the training images go
        imageOutputFilename  % the name of the current training image
        labelOutputDirectory % this is where the training labels go
        labelOutputFilename  % same as imageOutput...        
    end
    
    methods (Access = private)
        function uigetdirs(app)
            % Pick multiple files or directories using Java widgets                                                                                                                                                                                                                
            % by Andrew Janke 2011                                                                                                                                                                                                                                                 
            import javax.swing.JFileChooser;

            if (nargin == 0) % Allow a null argument.                                                                                                                                                                                      
                app.path{1} = pwd;
            end

            jchooser = javaObjectEDT('javax.swing.JFileChooser', app.path{1});

            jchooser.setFileSelectionMode(JFileChooser.FILES_AND_DIRECTORIES);
            if nargin > 1
                jchooser.setDialogTitle(dialog);
            end

            jchooser.setMultiSelectionEnabled(true);

            status = jchooser.showOpenDialog([]);

            if status == JFileChooser.APPROVE_OPTION
                jFile = jchooser.getSelectedFiles();
                app.path{size(jFile, 1)}=[];
                for i=1:size(jFile, 1)
                	app.path{i} = char(jFile(i).getAbsolutePath);
                end

            elseif status == JFileChooser.CANCEL_OPTION
                app.path{1} = [];
            else
                error('Error occured while picking file.');
            end 
        app.path = app.path';   
        end
        
        function addFiles(app)
            filesAVI = dir([app.SelectCurrentDirectory.Value, filesep,'*.avi']);
            filesMP4 = dir([app.SelectCurrentDirectory.Value, filesep,'*.mp4']);
            fileNames = [filesAVI; filesMP4];
            for jj = 1:length(fileNames)
                fileName = fileNames(jj).name;
                FileItems{jj} = fileName;
            end
            app.SelectCurrentFilename.Items     = FileItems;
            app.SelectCurrentFilename.ItemsData = FileItems;
            app.SelectCurrentFilename.Value     = FileItems{1};
        end
        
        function load_video(app)                                                                                                                                                                                    
            % USAGE: app = load_video(app)
            %
            % function reads video, grid, and camera parameters;
            % loads the current video, but does not rectify.
            [info, icp, beta0] = define_camera_parameters();
            %
            app.info = info;
            app.icp  = icp;
            app.beta0= app.beta0;
            %
            videoDir = [app.SelectCurrentDirectory.Value, filesep];
            videoFile= app.SelectCurrentFilename.Value;
            timeFile = [videoDir, filesep, videoFile(1:end-4),'.xml']; %find all videos in source folder
            fprintf('\n loading video %s \n',app.SelectCurrentFilename.Value)
            %
            % load video
            app.vid   = VideoReader([videoDir,filesep,videoFile]);
            vidHz = app.vid.FrameRate;
            vidDr = app.vid.Duration;
%
            samp  = round(vidHz/app.info.freq);
            app.videoFrameIndices = 1:samp:vd.NumFrames;
            app.numberVideoFrames = length(app.videoFrameIndices);
%
            % prepare image (U,V) coordinates for rectification
            [app.U, app.V] = meshgrid(0:icp.NU-1, 0:icp.NV-1);
            % generate grid (X,Y) coords for transformation
            [app.X,app.Y] = meshgrid(info.X_min:info.X_res:info.X_max,info.Y_min:info.Y_res:info.Y_max);   %define image grid
            % may need to correct for tide later
%                 %generate time                                                                                                                                                                                       
% %     fid = fopen(timeFile);                                                                                                                                                                              
% %     l = fgetl(fid);                                                                                                                                                                                      
% %     fclose(fid);                                                                                                                                                                                         
% %     startTimeStr = regexp(l, '(?<=<StartTime>).*(?=</StartTime>)', 'match');                                                                                                                             
% %     keyboard                                                                                                                                                                                             
% %     startTime = datetime(startTimeStr{1}, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss.SSSSSS''Z''', 'TimeZone', 'UTC', 'Format', 'yyyy-MM-dd HH:mm:ss.SSSS');                                                
% % %}
% %             %load NAVD water level                                                                                                                                                                                   
% %             [tz,z_tide] = load_FRF_waterlevels(startDate,endDate);
%             z_tide    = zeros(size(app.X));
%             % map grid points to image coords
%             [app.Uint,app.Vint]  = getUVfromXYZ(app.X,app.Y, z_tide, icp, beta0);
%             %
%             % get first frame
%             frameNum = app.StartFrameEditField.Value;
%             % load frame
%             IMGraw  = double(rgb2gray(read(app.vid,1)));
%             % interpolate to grid points
%             app.IMG = interp2(app.U, app.V, IMGraw, app.Uint, app.Vint, 'linear', nan);
            keyboard
        end
        
        function rectify_video(app)
          % USAGE: app = rectify_video(app)
          %
          % rectify 3 frames beginning with Start Frame, 
          % then advance Start Frame by 1.
          
            %generate time                                                                                                                                                                                       
%     fid = fopen(timeFile);                                                                                                                                                                              
%     l = fgetl(fid);                                                                                                                                                                                      
%     fclose(fid);                                                                                                                                                                                         
%     startTimeStr = regexp(l, '(?<=<StartTime>).*(?=</StartTime>)', 'match');                                                                                                                             
%     keyboard                                                                                                                                                                                             
%     startTime = datetime(startTimeStr{1}, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss.SSSSSS''Z''', 'TimeZone', 'UTC', 'Format', 'yyyy-MM-dd HH:mm:ss.SSSS');                                                
% %}
%             %load NAVD water level                                                                                                                                                                                   
%             [tz,z_tide] = load_FRF_waterlevels(startDate,endDate);
            z_tide    = zeros(size(app.X));
            % map grid points to image coords
            [app.Uint,app.Vint]  = getUVfromXYZ(app.X,app.Y, z_tide, icp, beta0);
            %
            % get first frame
            frameNum = app.StartFrameEditField.Value;
            frames   = app.videoFrameIndices(frameNum:frameNum+2);
            for ii   = 1:3;
                % load frame    
                IMGraw  = double(rgb2gray(read(app.vid,1)));
                % interpolate to grid points
                app.IMG(:,:,ii) = interp2(app.U, app.V, IMGraw, app.Uint, app.Vint, 'linear', nan);
            end
            app.StartFrameEditField.Value = min(frameNum+1,app.numberVideoFrames);
            keyboard
        end
        
        function rectify_and_display(app)
            % need a stop index for this loop similar to
            % StartFrameEditField
            for jj = 1:10
                rectify_vd(app)
                
                % now plot the current image
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFiles
        function SelectFilesPushed(app, event)
            app.path = [];
            % raw-data parent directory                                                                                                                                                                                                                                            
            %[stat,rootDir] = system('git rev-parse --show-toplevel');
            %app.path{1}    = rootDir(1:end-1);% drop the \n character  
            app.path{1} = pwd;
            uigetdirs(app)
            if isempty(app.path)
                app.TextArea.Value = "no directory selected, try again";
                return
            end

            % add these to the directory selector
            for jj = 1:length(app.path)
                FolderNames = regexp(app.path{jj},filesep,'split');
                DirectoryItems{jj} = [char(FolderNames{end-1}), filesep,char(FolderNames{end})];
            end
            app.SelectCurrentDirectory.Items     = DirectoryItems;
            app.SelectCurrentDirectory.ItemsData = app.path;
            app.SelectCurrentDirectory.Value     = app.path{1};
            %
            % add the filenames to the list
            addFiles(app);
            
      
            %fapp.SelectCurrentFilename.ItemsData
            
        end

        % Value changed function: SelectCurrentFilename
        function SelectCurrentFilenameValueChanged(app, event)
            app.SelectCurrentFilename.Value = event.Value;
%             value = app.SelectCurrentFilename.Value;
            %workingDir = app.SelectCurrentDirectory;
            %workingDir = char(workingDir(1))
            % videoPath = [app.SelectCurrentDirectory.Value, filesep, app.SelectCurrentFilename.Value];
        end

        % Drop down opening function: SelectCurrentFilename
        function SelectCurrentFilenameDropDownOpening(app, event)
            addFiles(app);           
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
              load_video(app);
              rectify_and_display(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectFiles = uibutton(app.UIFigure, 'push');
            app.SelectFiles.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesPushed, true);
            app.SelectFiles.Text = 'Select Video Files';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.SelectDirectoryLabel = uilabel(app.UIFigure);
            app.SelectDirectoryLabel.Text = 'Select Directory:';
            app.SelectCurrentDirectory = uidropdown(app.UIFigure);
            app.SelectFileLabel = uilabel(app.UIFigure);
            app.SelectFileLabel.Text = 'Select File:';
            app.SelectCurrentFilename = uidropdown(app.UIFigure);
            app.SelectCurrentFilename.DropDownOpeningFcn = createCallbackFcn(app, @SelectCurrentFilenameDropDownOpening, true);
            app.SelectCurrentFilename.ValueChangedFcn = createCallbackFcn(app, @SelectCurrentFilenameValueChanged, true);
            app.StartFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartFrameEditField.Limits = [1 Inf];
            app.StartFrameEditField.RoundFractionalValues = 'on';
            app.StartFrameEditField.Value = 1;
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_CNN_training_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end